classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CloseApplicationButton      matlab.ui.control.Button
        UpdateParametersButton      matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Video = ba_config_video('Grasshopper3');
        vid;
        videoInputNumber = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mkdir 'SessionData'
            %app.vid = videoinput('winvideo', app.videoInputNumber);
            app.vid = videoinput('pointgrey', 2, 'F7_Raw16_1024x768_Mode2');
            
            % m = load([INSERT FILEPATH TO EXISTING PROPERTIES HERE!]);
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
            
            src = getselectedsource(app.vid); 
            src.ExposureMode = app.Video.ExposureMode;
            src.FrameRateMode = app.Video.FrameRateMode;
            src.ShutterMode = app.Video.ShutterMode;
            src.Gain = app.Video.Gain;
            src.Gamma = app.Video.Gamma;
            src.Brightness = app.Video.Brightness;
            src.Shutter = app.Video.ExposureTime;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
        
            edit_exptime = uicontrol(f, 'Position', [20 20 60 20], ...
                                        'Style', 'edit', ...
                                        'String', num2str(src.Shutter), ...
                                        'Callback', @change_exptime);
            btn_grabframe = uicontrol(f, 'Position', [20 40 60 20], ...
                                         'Style', 'pushbutton', ...
                                         'String', 'Grab Frame', ...
                                         'Callback', @grab_frame);
        %     edit_exptime.Position
        %     btn_grabframe.Position
            
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @ba_livehist);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end


            function change_exptime(source,event)
        
                exptime = str2num(source.String);
                fprintf('New exposure time is: %4.2g\n', exptime);
                
                src.Shutter = exptime;
               
                edit_exptime.String = num2str(src.Shutter);
                
            end
        
            function grab_frame(source, event)
                imwrite(hImage.CData, 'grabframe.png');
                disp('Frame grabbed to grabframe.png');
            end
        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            close all
            closereq
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            close all
            
            src.ExposureMode = app.ExposureModeDropDown.Value;
            src.FrameRateMode = app.FrameRateModeDropDown.Value;
            src.ShutterMode = app.ShutterModeDropDown.Value;
            src.Gain = app.GammaEditField.Value;
            src.Gamma = app.GammaEditField.Value;
            src.Brightness = app.BrightnessEditField.Value;
            src.Shutter = app.ExposureTimeEditField.Value;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
        
            edit_exptime = uicontrol(f, 'Position', [20 20 60 20], ...
                                        'Style', 'edit', ...
                                        'String', num2str(src.Shutter), ...
                                        'Callback', @change_exptime);
            btn_grabframe = uicontrol(f, 'Position', [20 40 60 20], ...
                                         'Style', 'pushbutton', ...
                                         'String', 'Grab Frame', ...
                                         'Callback', @grab_frame);
        %     edit_exptime.Position
        %     btn_grabframe.Position
            
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @ba_livehist);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end


            function change_exptime(source,event)
        
                exptime = str2num(source.String);
                fprintf('New exposure time is: %4.2g\n', exptime);
                
                src.Shutter = exptime;
               
                edit_exptime.String = num2str(src.Shutter);
                
            end
        
            function grab_frame(source, event)
                imwrite(hImage.CData, 'grabframe.png');
                disp('Frame grabbed to grabframe.png');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CloseApplicationButton = uibutton(app.UIFigure, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            app.UpdateParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.ExposureModeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.UIFigure);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateModeDropDownLabel = uilabel(app.UIFigure);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.UIFigure);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.UIFigure);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.UIFigure);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end